classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputpathxlsxcsvtxtLabel       matlab.ui.control.Label
        InputpathxlsxcsvtxtEditField   matlab.ui.control.EditField
        RunSVMButton                   matlab.ui.control.Button
        StartcolumnEditFieldLabel      matlab.ui.control.Label
        StartcolumnEditField           matlab.ui.control.NumericEditField
        FinalcolumnEditFieldLabel      matlab.ui.control.Label
        FinalcolumnEditField           matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        SimpleSupportVectorMachineLabel  matlab.ui.control.Label
        SummarizationTextAreaLabel     matlab.ui.control.Label
        SummarizationTextArea          matlab.ui.control.TextArea
        ClassificationtypeButtonGroup  matlab.ui.container.ButtonGroup
        BinaryButton                   matlab.ui.control.RadioButton
        MultiButton                    matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
            predNum = 3400;
            inputdir = app.InputpathxlsxcsvtxtEditField.Value;
            startc = app.StartcolumnEditField.Value;
            finalc = app.FinalcolumnEditField.Value;
            d = uiprogressdlg(app.UIFigure,"Title","Please Wait","Message","Loading models...");
            if app.BinaryButton.Value
                load holdout.mat mu coeff featnum mdl; 
            elseif app.MultiButton.Value
                load multiholdout.mat mu coeff featnum mdl;
            end
            
            try
                % Check start and stop column
                if startc < 1
                    throw(MException("callback:toolowstart","Start position too low."));
                elseif (finalc < startc) && (finalc ~= -1)
                    throw(MException("callback:finalsmallerthanstart","Final position smaller than start position."));
                end
                
                % Reading data from path
                d.Value = 0.33;
                d.Message = "Reading table...";
                t = readtable(inputdir,"ReadVariableNames",false);
                if finalc ~= -1
                    t(:,finalc+1:end)=[];
                end
                if startc > 1
                    t(:,1:startc-1)=[];
                end
                
                % Check predictors number
                dim = size(t);
                if dim(2) ~= predNum
                    throw(MException("callback:lackpred","Wrong number of predictors."));
                end
                
                % Transform data
                d.Value = 0.60;
                d.Message = "Transforming data...";
                xin = fftmat(t{:,:});
                pca_xin = (xin - mu)*coeff(:,1:featnum);
                
                % Labeling
                d.Value = 0.80;
                d.Message = "Labeling data...";
                pred = predict(mdl,pca_xin);
                d.Value = 0.99;
                d.Message = "Labeled data...";
                
                % Summarization
                textval = "";
                summ = table(categories(pred),countcats(pred));
                summrow = size(summ,1);
                for i = 1:summrow
                    texttemp = sprintf("%s: %d\n",string(summ{i,1}),summ{i,2});
                    textval = textval + texttemp;
                end
                app.SummarizationTextArea.Value = textval;
                
                % Showing tabular data
                pred = table(pred);
                tab = table;
                temp = table;
                for i = 1:5
                    tab(:,i) = t(:,i);
                end
                temp{1:dim(1),1} = "...";
                tab(:,6) = temp;
                tab(:,7) = pred;
                
                app.UITable.Data = tab;
                app.UITable.ColumnName = ["t1","t2","t3","t4","t5","...","Object"];
                app.UITable.RowName = 1:dim(1);
                
                % Plotting classified data in reduced dimension
                % Multiclassfication
                if app.MultiButton.Value
                    colmap_o1 = pred{:,:} == "Object 1";
                    colmap_o2 = pred{:,:} == "Object 2";
                    colmap_o3 = pred{:,:} == "Object 3";
                    colmap_o4 = pred{:,:} == "Object 4";
                    colmap_o5 = pred{:,:} == "Object 5";
                    pred_o1_pca_xin = pca_xin(colmap_o1,1:2);
                    pred_o2_pca_xin = pca_xin(colmap_o2,1:2);
                    pred_o3_pca_xin = pca_xin(colmap_o3,1:2);
                    pred_o4_pca_xin = pca_xin(colmap_o4,1:2);
                    pred_o5_pca_xin = pca_xin(colmap_o5,1:2);
                    scatter(app.UIAxes,pred_o1_pca_xin(:,1),pred_o1_pca_xin(:,2),20,'red',"filled");
                    hold(app.UIAxes,"on");
                    scatter(app.UIAxes,pred_o2_pca_xin(:,1),pred_o2_pca_xin(:,2),20,'yellow',"filled");
                    scatter(app.UIAxes,pred_o3_pca_xin(:,1),pred_o3_pca_xin(:,2),20,'green',"filled");
                    scatter(app.UIAxes,pred_o4_pca_xin(:,1),pred_o4_pca_xin(:,2),20,'blue',"filled");
                    scatter(app.UIAxes,pred_o5_pca_xin(:,1),pred_o5_pca_xin(:,2),20,'cyan',"filled");
                    legend(app.UIAxes,"Object 1","Object 2","Object 3","Object 4","Object 5");
                    hold(app.UIAxes,"off");
                elseif app.BinaryButton.Value
                    colmap_o1 = pred{:,:} == "Object 1";
                    colmap_no1 = ~colmap_o1;
                    pred_o1_pca_xin = pca_xin(colmap_o1,1:2);
                    pred_no1_pca_xin = pca_xin(colmap_no1,1:2);
                    scatter(app.UIAxes,pred_o1_pca_xin(:,1),pred_o1_pca_xin(:,2),20,'red',"filled");
                    hold(app.UIAxes,"on");
                    scatter(app.UIAxes,pred_no1_pca_xin(:,1),pred_no1_pca_xin(:,2),20,'blue',"filled");
                    legend(app.UIAxes,"Object 1","Not Object 1");
                    hold(app.UIAxes,"off");
                end

            catch ME
                uialert(app.UIFigure,ME.message,"Error");
            end
            
            %% AUXILIARY FUNCTION

            % Perform FFT on each row of the input matrix and return only 1/4 of the
            % FFT result for each row
            function retmat = fftmat(mat)
                retmat_temp = fft(mat');
                retmat = retmat_temp';
                fin = ceil(size(mat,2)/4);
                retmat = abs(retmat(:,1:fin));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputpathxlsxcsvtxtLabel = uilabel(app.UIFigure);
            app.InputpathxlsxcsvtxtLabel.Text = {'Input path'; '(xlsx, csv, txt) '};
            app.InputpathxlsxcsvtxtEditField = uieditfield(app.UIFigure, 'text');
            app.InputpathxlsxcsvtxtEditField.Value = 'D:\Document\3-FRA-UAS_MA_2020-2021\Machine Learning_Dr-Pech\Files for task 1 and task 6\Object 1\record_data_2020-10-29_10-42-0_H.xlsx';
            app.RunSVMButton = uibutton(app.UIFigure, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.StartcolumnEditFieldLabel = uilabel(app.UIFigure);
            app.StartcolumnEditFieldLabel.Text = 'Start column';
            app.StartcolumnEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartcolumnEditField.Value = 7;
            app.FinalcolumnEditFieldLabel = uilabel(app.UIFigure);
            app.FinalcolumnEditFieldLabel.Text = 'Final column';
            app.FinalcolumnEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalcolumnEditField.Value = -1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SimpleSupportVectorMachineLabel = uilabel(app.UIFigure);
            app.SimpleSupportVectorMachineLabel.Text = 'Simple Support Vector Machine';
            app.SummarizationTextAreaLabel = uilabel(app.UIFigure);
            app.SummarizationTextAreaLabel.Text = 'Summarization';
            app.SummarizationTextArea = uitextarea(app.UIFigure);
            app.ClassificationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationtypeButtonGroup.Title = 'Classification type';
            app.BinaryButton = uiradiobutton(app.ClassificationtypeButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.BinaryButton.Value = true;
            app.MultiButton = uiradiobutton(app.ClassificationtypeButtonGroup);
            app.MultiButton.Text = 'Multi-';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data in reduced dimension')
            xlabel(app.UIAxes, 'PC1')
            ylabel(app.UIAxes, 'PC2')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end