classdef verifyapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BinarySVMwithPCALabel        matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        TrainingTab                  matlab.ui.container.Tab
        TrainSVMButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        FilepathEditFieldLabel       matlab.ui.control.Label
        FilepathEditField            matlab.ui.control.EditField
        PositivelabelEditFieldLabel  matlab.ui.control.Label
        PositivelabelEditField       matlab.ui.control.EditField
        StartcolumnEditFieldLabel    matlab.ui.control.Label
        StartcolumnEditField         matlab.ui.control.NumericEditField
        StopcolumnEditFieldLabel     matlab.ui.control.Label
        StopcolumnEditField          matlab.ui.control.NumericEditField
        NumberoffeaturesAfterusingPCAEditFieldLabel  matlab.ui.control.Label
        NumberoffeaturesAfterusingPCAEditField  matlab.ui.control.NumericEditField
        HoldoutratioEditFieldLabel   matlab.ui.control.Label
        HoldoutratioEditField        matlab.ui.control.NumericEditField
        SVMkernelDropDownLabel       matlab.ui.control.Label
        SVMkernelDropDown            matlab.ui.control.DropDown
        UIAxes3                      matlab.ui.control.UIAxes
        DeploymentTab                matlab.ui.container.Tab
        RunSVMButton                 matlab.ui.control.Button
        StartcolumnEditField_2Label  matlab.ui.control.Label
        StartcolumnEditField_2       matlab.ui.control.NumericEditField
        StopcolumnEditField_2Label   matlab.ui.control.Label
        StopcolumnEditField_2        matlab.ui.control.NumericEditField
        SummarizationTextAreaLabel   matlab.ui.control.Label
        SummarizationTextArea        matlab.ui.control.TextArea
        InputfileEditFieldLabel      matlab.ui.control.Label
        InputfileEditField           matlab.ui.control.EditField
        UITable3                     matlab.ui.control.Table
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainSVMButton
        function TrainSVMButtonPushed(app, event)
            % Global variables
            global varsize mu coeff mdl featnum poslabel;    
            try 
                % Create UI loading bar
                d = uiprogressdlg(app.UIFigure,"Title","Please wait...", "Message","Opening the application");
                
                %% CONSTANTS
                % Training and validation phase
                dir = app.FilepathEditField.Value; % Directory containing all training data (the last letter of each file name MUST be its data's label)
                poslabel = app.PositivelabelEditField.Value; % Positive label
                startcol = app.StartcolumnEditField.Value; % Starting column of data
                finalcol = app.StopcolumnEditField.Value; % Final column of data
                varsize = finalcol - startcol + 1; % Number of columns of time data
                featnum = app.NumberoffeaturesAfterusingPCAEditField.Value; % Number of features to take after PCA
                holdoutratio = app.HoldoutratioEditField.Value; % Ratio between training- and testing-dataset
                kernelfunc = app.SVMkernelDropDown.Value; % Kernel function to be used for the SVM
                
                %% READING DATA FROM FILES
                
                % UI loading bar
                d.Value = 0.33;
                d.Message = "Reading table from file...";
                % Read data as table from xlsx
                t_all = table;
                ds = datastore(dir,"IncludeSubfolders",true,"ReadVariableNames",false);
                
                for file = 1:size(ds.Files,1)
                    % Read raw data and trim metadata
                    [t, info] = read(ds);
                    t(:,1:startcol-1)=[];
                    t(:,finalcol+1:end) = [];
                    
                    % Extracting label from file name
                    obj_temp = extractBefore(info.Filename,'.');
                    obj = obj_temp(end);
                    
                    % Add label as the final column of the data matrix
                    t.Object(:,1) = obj; % Add label to data
                    t.Object = cellstr(t.Object);
                    t.Object = categorical(t.Object);
                    t_all = [t_all;t];
                end
                
                % Generate name for time sample data columns (features)
                varcell = {};
                for i = 1:varsize
                    b = num2str(i);
                    varcell{1,i} = strcat('t',b);
                end
                varcell(1,end+1) = {'Object'};
                t_all.Properties.VariableNames = varcell; % Rename time data columns (features)
                
                %% TRAINING SVM MODEL
                
                % UI loading bar
                d.Value = 0.66;
                d.Message = "Training model...";
                
                % Re-labeling
                t_all.Object(t_all.Object~=poslabel) = "Not " + poslabel;
                t_all.Object = removecats(t_all.Object);
                
                % Partition data
                cvpt = cvpartition(t_all.Object,"Holdout",holdoutratio);
                traindata = t_all(training(cvpt),:);
                testdata = t_all(test(cvpt),:);
                xtrain = traindata{:,1:end-1};
                ytrain = traindata{:,end};
                xtest = testdata{:,1:end-1};
                ytest = testdata{:,end};
                
                % Feature reduction - PCA
                [coeff,scoreTrain,~,~,explained,mu] = pca(xtrain);
                
                % Plotting Pareto chart of PCs
                bar(app.UIAxes3,explained(1:featnum));
                c_explained = zeros(1,featnum);
                for id = 1:featnum
                    if id == 1
                        c_explained(id) = explained(id);
                    else
                        c_explained(id) = c_explained(id-1) + explained(id); 
                    end
                end
                hold(app.UIAxes3,"on");
                plot(app.UIAxes3,c_explained);
                hold(app.UIAxes3,"off");
                
                % Get transformed data with selected feature
                pca_xtrain = scoreTrain(:,1:featnum);
                
                % Partition data and fit SVM model (Hold-out)
                mdl = fitcsvm(pca_xtrain,ytrain,"KernelFunction",kernelfunc,"Standardize",true);
                
                % Transform test data
                pca_xtest = (xtest-mu)*coeff(:,1:featnum);
                
                % Classify test data using trained model
                pred = predict(mdl,pca_xtest);
                
                % UI loading bar
                d.Value = 0.8;
                d.Message = "Calculating stats...";
                
                % Calculating stats
                iswrongneg = (pred ~= ytest) & (pred == "Not " + poslabel);
                istrueneg = (pred == ytest) & (pred == "Not " + poslabel);
                FN = sum(iswrongneg);
                TN = sum(istrueneg);
                iswrongpos = (pred ~= ytest) & (pred == poslabel);
                istruepos = (pred == ytest) & (pred == poslabel);
                FP = sum(iswrongpos);
                TP = sum(istruepos);
                
                FDR = FP / (FP + TP);
                NPV = TN / (TN + FN);
                TPR = TP / (TP + FN);
                TNR = TN / (TN + FP);
                
                F1 = (2*TP)/(2*TP + FP + FN);
                
                % Misclassification Error
                % missRate = loss(mdl,testdata,"Object");
                missRate = (FP + FN)/(TP + TN);
                
                % Confusion matrix
                conmat = confusionmat(ytest,pred);
                
                % % Summing up
                % sumup = table(TP,FP,TN,FN,FDR,NPV,TPR,TNR,F1,missRate)
                
                %% DISPLAYING RESULTS
                
                % UI loading bar
                d.Value = 0.9;
                d.Message = "Displaying result...";
                
                % Confustion matrix
                app.UITable.Data = conmat;
                % Summarizing stats
                app.UITable2.Data = [TP FP TN FN FDR NPV TPR TNR F1 missRate];
                % PCA data representation
                colmap = pred == poslabel;
                pred_h_pca_xtest = pca_xtest(colmap,:);
                pred_not_h_pca_xtest = pca_xtest(~colmap,:);
                scatter(app.UIAxes,pred_h_pca_xtest(:,1),pred_h_pca_xtest(:,2),20,'r',"filled");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,pred_not_h_pca_xtest(:,1),pred_not_h_pca_xtest(:,2),20,'b',"filled");
                legend(app.UIAxes,poslabel,strcat("Not",poslabel));
                hold(app.UIAxes,"off");             
                % UI loading bar
                close(d);
            catch ME
                uialert(app.UIFigure,ME.message,"Error");
            end
        end

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
            % Global variables
            global varsize mu coeff mdl featnum poslabel;  
            try
                %% CONSTANTS
                predNum = varsize;
                inputdir = app.InputfileEditField.Value;
                startc = app.StartcolumnEditField_2.Value;
                finalc = app.StopcolumnEditField_2.Value;
                d = uiprogressdlg(app.UIFigure,"Title","Please Wait","Message","Loading models...");
                
                % Check start and stop column
                if startc < 1
                    throw(MException("callback:toolowstart","Start position too low."));
                elseif (finalc < startc) && (finalc ~= -1)
                    throw(MException("callback:finalsmallerthanstart","Final position smaller than start position."));
                end
                
                % Reading data from path
                d.Value = 0.33;
                d.Message = "Reading table...";
                t = readtable(inputdir,"ReadVariableNames",false);
                if finalc ~= -1
                    t(:,finalc+1:end)=[];
                end
                if startc > 1
                    t(:,1:startc-1)=[];
                end
                
                % Check predictors number
                dim = size(t);
                if dim(2) ~= predNum
                    throw(MException("callback:lackpred","Wrong number of predictors."));
                end
                
                % Transform data
                d.Value = 0.60;
                d.Message = "Transforming data...";
                xin = t{:,:};
                pca_xin = (xin - mu)*coeff(:,1:featnum);
                
                % Labeling
                d.Value = 0.80;
                d.Message = "Labeling data...";
                pred = predict(mdl,pca_xin);
                d.Value = 0.99;
                d.Message = "Labeled data...";
                
                % Summarization
                textval = "";
                summ = table(categories(pred),countcats(pred));
                summrow = size(summ,1);
                for i = 1:summrow
                    texttemp = sprintf("%s: %d\n",string(summ{i,1}),summ{i,2});
                    textval = textval + texttemp;
                end
                app.SummarizationTextArea.Value = textval;
                
                % Showing data
                pred = table(pred);
                tab = table;
                temp = table;
                for i = 1:5
                    tab(:,i) = t(:,i);
                end
                temp{1:dim(1),1} = "...";
                tab(:,6) = temp;
                tab(:,7) = pred;
                
                app.UITable3.Data = tab;
                app.UITable3.ColumnName = ["t1","t2","t3","t4","t5","...","Object"];
                app.UITable3.RowName = 1:dim(1);
                
                % Plotting data in reduced dimension
                colmap_o1 = pred{:,:} == poslabel;
                colmap_no1 = ~colmap_o1;
                pred_o1_pca_xin = pca_xin(colmap_o1,1:2);
                pred_no1_pca_xin = pca_xin(colmap_no1,1:2);
                scatter(app.UIAxes2,pred_o1_pca_xin(:,1),pred_o1_pca_xin(:,2),20,'red',"filled");
                hold(app.UIAxes2,"on");
                scatter(app.UIAxes2,pred_no1_pca_xin(:,1),pred_no1_pca_xin(:,2),20,'blue',"filled");
                legend(app.UIAxes2,poslabel,strcat("Not",poslabel));
                hold(app.UIAxes,"off");
            catch ME
                uialert(app.UIFigure,ME.message,"Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BinarySVMwithPCALabel = uilabel(app.UIFigure);
            app.BinarySVMwithPCALabel.Text = 'Binary SVM with PCA';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainSVMButton = uibutton(app.TrainingTab, 'push');
            app.TrainSVMButton.ButtonPushedFcn = createCallbackFcn(app, @TrainSVMButtonPushed, true);
            app.TrainSVMButton.Text = 'Train SVM';
            app.UITable = uitable(app.TrainingTab);
            app.UITable.ColumnName = {'H (predicted)'; 'Not H (predicted)'};
            app.UITable.RowName = {'H'; 'Not H'};
            app.UITable2 = uitable(app.TrainingTab);
            app.UITable2.ColumnName = {'TP'; 'FP'; 'TN'; 'FN'; 'FDR'; 'NPV'; 'TPR'; 'TNR'; 'F1'; 'missRate'};
            app.UITable2.RowName = {};
            app.UIAxes = uiaxes(app.TrainingTab);
            title(app.UIAxes, 'Data representation with PCA (2 PCs)')
            xlabel(app.UIAxes, 'PC1')
            ylabel(app.UIAxes, 'PC2')
            app.FilepathEditFieldLabel = uilabel(app.TrainingTab);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.TrainingTab, 'text');
            app.FilepathEditField.Value = 'D:/Document/3-FRA-UAS_MA_2020-2021/Machine Learning_Dr-Pech/Files for task 1 and task 6/';
            app.PositivelabelEditFieldLabel = uilabel(app.TrainingTab);
            app.PositivelabelEditFieldLabel.Text = 'Positive label';
            app.PositivelabelEditField = uieditfield(app.TrainingTab, 'text');
            app.PositivelabelEditField.Value = 'H';
            app.StartcolumnEditFieldLabel = uilabel(app.TrainingTab);
            app.StartcolumnEditFieldLabel.Text = 'Start column';
            app.StartcolumnEditField = uieditfield(app.TrainingTab, 'numeric');
            app.StartcolumnEditField.Value = 7;
            app.StopcolumnEditFieldLabel = uilabel(app.TrainingTab);
            app.StopcolumnEditFieldLabel.Text = 'Stop column';
            app.StopcolumnEditField = uieditfield(app.TrainingTab, 'numeric');
            app.StopcolumnEditField.Value = 3406;
            app.NumberoffeaturesAfterusingPCAEditFieldLabel = uilabel(app.TrainingTab);
            app.NumberoffeaturesAfterusingPCAEditFieldLabel.Text = {'Number of features'; '(After using PCA)'};
            app.NumberoffeaturesAfterusingPCAEditField = uieditfield(app.TrainingTab, 'numeric');
            app.NumberoffeaturesAfterusingPCAEditField.Value = 8;
            app.HoldoutratioEditFieldLabel = uilabel(app.TrainingTab);
            app.HoldoutratioEditFieldLabel.Text = 'Holdout ratio';
            app.HoldoutratioEditField = uieditfield(app.TrainingTab, 'numeric');
            app.HoldoutratioEditField.Value = 0.2;
            app.SVMkernelDropDownLabel = uilabel(app.TrainingTab);
            app.SVMkernelDropDownLabel.Text = 'SVM kernel';
            app.SVMkernelDropDown = uidropdown(app.TrainingTab);
            app.SVMkernelDropDown.Items = {'linear', 'polynomial', 'gaussian', 'rbf'};
            app.SVMkernelDropDown.Value = 'polynomial';
            app.UIAxes3 = uiaxes(app.TrainingTab);
            title(app.UIAxes3, 'Pareto chart of PCs')
            xlabel(app.UIAxes3, 'PC')
            ylabel(app.UIAxes3, '% explanation')
            app.DeploymentTab = uitab(app.TabGroup);
            app.DeploymentTab.Title = 'Deployment';
            app.RunSVMButton = uibutton(app.DeploymentTab, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.StartcolumnEditField_2Label = uilabel(app.DeploymentTab);
            app.StartcolumnEditField_2Label.Text = 'Start column';
            app.StartcolumnEditField_2 = uieditfield(app.DeploymentTab, 'numeric');
            app.StartcolumnEditField_2.Value = 7;
            app.StopcolumnEditField_2Label = uilabel(app.DeploymentTab);
            app.StopcolumnEditField_2Label.Text = 'Stop column';
            app.StopcolumnEditField_2 = uieditfield(app.DeploymentTab, 'numeric');
            app.StopcolumnEditField_2.Value = -1;
            app.SummarizationTextAreaLabel = uilabel(app.DeploymentTab);
            app.SummarizationTextAreaLabel.Text = 'Summarization';
            app.SummarizationTextArea = uitextarea(app.DeploymentTab);
            app.InputfileEditFieldLabel = uilabel(app.DeploymentTab);
            app.InputfileEditFieldLabel.Text = 'Input file';
            app.InputfileEditField = uieditfield(app.DeploymentTab, 'text');
            app.InputfileEditField.Value = 'D:\Document\3-FRA-UAS_MA_2020-2021\Machine Learning_Dr-Pech\Files for task 1 and task 6\Object 1\record_data_2020-10-29_10-41-1_H.xlsx';
            app.UITable3 = uitable(app.DeploymentTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UIAxes2 = uiaxes(app.DeploymentTab);
            title(app.UIAxes2, 'Data in reduced dimension')
            xlabel(app.UIAxes2, 'PC2')
            ylabel(app.UIAxes2, 'PC1')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifyapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end